classdef ViewerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GiroButtonGroup            matlab.ui.container.ButtonGroup
        CCWButton                  matlab.ui.control.RadioButton
        CWButton                   matlab.ui.control.RadioButton
        TabGroup                   matlab.ui.container.TabGroup
        CoordenadasPolaresTab      matlab.ui.container.Tab
        CreacindearchivosPanel_3   matlab.ui.container.Panel
        GuardarmatButton_3         matlab.ui.control.Button
        CoordenadasCartesianasTab  matlab.ui.container.Tab
        CreacindearchivosPanel_2   matlab.ui.container.Panel
        GuardarmatButton_2         matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        CapturademuestrasPanel     matlab.ui.container.Panel
        CaptureLamp                matlab.ui.control.Lamp
        CapturaButton              matlab.ui.control.Button
        NoMuestrasEditField        matlab.ui.control.NumericEditField
        NoMuestrasEditFieldLabel   matlab.ui.control.Label
        ConexinPanel               matlab.ui.container.Panel
        BuscarPuertosButton        matlab.ui.control.Button
        PuertoCOMDropDown          matlab.ui.control.DropDown
        PuertoCOMDropDownLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        Pax % Polar axes
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create polar axes and position it in pixels
            app.Pax = polaraxes(app.CoordenadasPolaresTab);
            app.Pax.Units = "pixels";
            app.Pax.Position = [16,16,320,320];
        end

        % Value changed function: PuertoCOMDropDown
        function PuertoCOMDropDownValueChanged(app, event)

            % Una vez se seleccione el puerto al que se desea conectar, se
            % conecta al puerto serial, se limpia el buffer de entrada y se
            % especifica el puerto al que se conectó.
            %
            com_port = app.PuertoCOMDropDown.Value;
            serial_mon = serialport(com_port, 230400);
            flush(serial_mon);
            app.COMLabel.Text = com_port;
            
        end

        % Button pushed function: BuscarPuertosButton
        function BuscarPuertosButtonPushed(app, event)
            % Busca los puertos disponibles y los agrega como opciones en
            % un dropdown para que el usuario seleccione el puerto al que
            % se desea conectar.
            %
            com_list = serialportlist("available");
            app.PuertoCOMDropDown.Items = com_list;
        end

        % Button pushed function: CapturaButton
        function CapturaButtonPushed(app, event)
            
            n = app.NoMuestrasEditField.Value;
            com_port = app.PuertoCOMDropDown.Value;
            LiDAR = serialport(com_port, 230400);

            x = "00000000000000000000000000000000000000000000000000000000";
            num_data = n;
            data = repmat(x, num_data, 1);
            app.CaptureLamp.Color = "#00FF00";
            flush(LiDAR)
            try
                for i = 1:num_data
                    buffer = readline(LiDAR);
                    if isempty(buffer)
                        disp('Vacio')
                    else
                        data(i, :) = buffer;
                    end 
                end
            catch
                warning('Try Again.')
            end
            app.CaptureLamp.Color = "#414141";
            points = 12;
            dist_angl = zeros(num_data*points, 5);
            index = 1;
            angles = repmat(3.14, 1, points);
            y_factor = 0.11923;
            x_offset = 5.9;
            y_offset = -18.975571;
            if app.CWButton.Value == 1
                right_hand = 1;
            elseif app.CCWButton.Value == 1
                right_hand = 0;
            end
            last_shift_delta = 0;
            shift = 0;

            for n = 1:num_data
                start_angle_l = hex2dec(extractBetween(data(n),1,2));
                start_angle_h = hex2dec(extractBetween(data(n),3,4));
                start_angle = bitor(bitshift(start_angle_h, 8), start_angle_l)/100;
                
                distances = zeros(points, 1);
                for m = 1:points
                    idx = 4 + (m-1)*4;
                    dist_l = hex2dec(extractBetween(data(n),idx+1,idx+2));
                    dist_h = hex2dec(extractBetween(data(n),idx+3,idx+4));
                    distances(m) = bitor(bitshift(dist_h, 8), dist_l);
                end
                
                end_angle_l = hex2dec(extractBetween(data(n),53,54));
                end_angle_h = hex2dec(extractBetween(data(n),55,56));
                end_angle = bitor(bitshift(end_angle_h, 8), end_angle_l)/100;
               
                if start_angle > end_angle
                    step_angle = (360 - start_angle + end_angle)/(points - 1);
                else
                    step_angle = (end_angle - start_angle)/(points - 1);
                end

                for l = 0:(points - 1)
                    angles(l + 1) = start_angle + step_angle * l;
                end
            
                for b = 1:points
                    if distances(b) > 0
                        x_val = distances(b) + x_offset;
                        y_val = distances(b) * y_factor + y_offset;
                        shift = rad2deg(atan(y_val/x_val));
                        if right_hand == 1
                            new_angle = (360 - angles(b)) + shift;
                        else
                            new_angle = angles(b) - shift;
                        end
                        last_shift_delta = shift;
                    else
                        if right_hand == 1
                            new_angle = (360 - angles(b)) + last_shift_delta;
                        else
                            new_angle = angles(b) - last_shift_delta;
                        end
                    end

                    if new_angle > 360
                        new_angle = new_angle - 360;
                    end
                    if new_angle < 0
                        new_angle = new_angle + 360;
                    end
                    dist_angl(index, 2) = distances(b);
                    dist_angl(index, 1) = deg2rad(angles(b));
                    dist_angl(index, 3) = deg2rad(new_angle);
                    dist_angl(index, 4) = angles(b);
                    dist_angl(index, 5) = new_angle;
                    index = index + 1;
                end
            end
            
            polarplot(app.Pax,dist_angl(:,3),dist_angl(:,2),'.')
            indx = 1;
            dist_cc = zeros(num_data*points, 2);
            for p = 1:num_data*points
                    dist_cc(indx,1) = dist_angl(p,2)*cos(dist_angl(p,3));
                    dist_cc(indx,2) = dist_angl(p,2)*sin(dist_angl(p,3));
                    indx =indx + 1;
            end
            plot(app.UIAxes,dist_cc(:,1),dist_cc(:,2),'.')
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConexinPanel = uipanel(app.UIFigure);
            app.ConexinPanel.Title = 'Conexión';
            app.PuertoCOMDropDownLabel = uilabel(app.ConexinPanel);
            app.PuertoCOMDropDownLabel.Text = 'Puerto COM';
            app.PuertoCOMDropDown = uidropdown(app.ConexinPanel);
            app.PuertoCOMDropDown.Items = {'...'};
            app.PuertoCOMDropDown.ValueChangedFcn = createCallbackFcn(app, @PuertoCOMDropDownValueChanged, true);
            app.PuertoCOMDropDown.Value = '...';
            app.BuscarPuertosButton = uibutton(app.ConexinPanel, 'push');
            app.BuscarPuertosButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarPuertosButtonPushed, true);
            app.BuscarPuertosButton.Text = 'Buscar Puertos';
            app.CapturademuestrasPanel = uipanel(app.UIFigure);
            app.CapturademuestrasPanel.Title = 'Captura de muestras';
            app.NoMuestrasEditFieldLabel = uilabel(app.CapturademuestrasPanel);
            app.NoMuestrasEditFieldLabel.Text = 'No. Muestras';
            app.NoMuestrasEditField = uieditfield(app.CapturademuestrasPanel, 'numeric');
            app.NoMuestrasEditField.Limits = [60 200];
            app.NoMuestrasEditField.Value = 60;
            app.CapturaButton = uibutton(app.CapturademuestrasPanel, 'push');
            app.CapturaButton.ButtonPushedFcn = createCallbackFcn(app, @CapturaButtonPushed, true);
            app.CapturaButton.Text = 'Captura';
            app.CaptureLamp = uilamp(app.CapturademuestrasPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CoordenadasPolaresTab = uitab(app.TabGroup);
            app.CoordenadasPolaresTab.Title = 'Coordenadas Polares';
            app.CreacindearchivosPanel_3 = uipanel(app.CoordenadasPolaresTab);
            app.CreacindearchivosPanel_3.Title = 'Creación de archivos';
            app.GuardarmatButton_3 = uibutton(app.CreacindearchivosPanel_3, 'push');
            app.GuardarmatButton_3.Text = 'Guardar .mat';
            app.CoordenadasCartesianasTab = uitab(app.TabGroup);
            app.CoordenadasCartesianasTab.Title = 'Coordenadas Cartesianas';
            app.UIAxes = uiaxes(app.CoordenadasCartesianasTab);
            title(app.UIAxes, 'Coordenadas')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.CreacindearchivosPanel_2 = uipanel(app.CoordenadasCartesianasTab);
            app.CreacindearchivosPanel_2.Title = 'Creación de archivos';
            app.GuardarmatButton_2 = uibutton(app.CreacindearchivosPanel_2, 'push');
            app.GuardarmatButton_2.Text = 'Guardar .mat';
            app.GiroButtonGroup = uibuttongroup(app.UIFigure);
            app.GiroButtonGroup.Title = 'Giro';
            app.CWButton = uiradiobutton(app.GiroButtonGroup);
            app.CWButton.Text = 'CW';
            app.CWButton.Value = true;
            app.CCWButton = uiradiobutton(app.GiroButtonGroup);
            app.CCWButton.Text = 'CCW';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ViewerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end